classdef vector_solution_viz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        fix_center                    matlab.ui.control.Switch
        FixcenterSwitchLabel          matlab.ui.control.Label
        y_translation                 matlab.ui.control.NumericEditField
        ytranslationEditFieldLabel    matlab.ui.control.Label
        x_translation                 matlab.ui.control.NumericEditField
        xtranslationEditFieldLabel    matlab.ui.control.Label
        misorientation                matlab.ui.control.NumericEditField
        MisorientationAngleEditFieldLabel  matlab.ui.control.Label
        fourier_degree                matlab.ui.control.Slider
        FourierDegreeSliderLabel      matlab.ui.control.Label
        FuzzierLabel                  matlab.ui.control.Label
        ClearerLabel                  matlab.ui.control.Label
        MorePreciseLabel              matlab.ui.control.Label
        LessPreciseLabel              matlab.ui.control.Label
        ComputationalParametersLabel  matlab.ui.control.Label
        FreeLatticeControlsLabel      matlab.ui.control.Label
        tick_spacing_denominator      matlab.ui.control.Slider
        PrecisionLabel                matlab.ui.control.Label
        center_resolution             matlab.ui.control.Slider
        CenterResolutionLabel         matlab.ui.control.Label
        axis_size                     matlab.ui.control.NumericEditField
        AxisSizeLabel                 matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        axes_fixed                    matlab.ui.control.UIAxes
        axes_free                     matlab.ui.control.UIAxes
        axes_super                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        X % x-indices
        Y % y-indices
        view_angle
        camera_position
        tick_marks
        fixed_lattice
        free_lattice
        lattice_spacing
        tick_spacing
        last_center
        movement_bool
        metalattice_spacing
        misorientation_rad

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
            app.last_center = [0,0];
            app.movement_bool=1;
            app.center_resolution.Value=0.05;
            app.lattice_spacing.Value=1;
            disp(app.lattice_spacing);
            app.tick_marks.Value=10;
            app.axis_size.Value = app.lattice_spacing.Value*app.tick_marks.Value;
            app.tick_spacing.Value=app.lattice_spacing.Value/4;
            
            app.misorientation_rad.Value = 5.5;
         
            [app.X,app.Y]=get_plane_indices(app.lattice_spacing.Value, app.tick_spacing.Value, app.axis_size.Value);

%             app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y);

            plot_component_lattice(app.axes_fixed, app.fixed_lattice, app.X, app.Y);
            xlim(app.axes_fixed, [-5,5]);
            ylim(app.axes_fixed, [-5,5]);
            
            xlim(app.axes_free, [-5,5]);
            ylim(app.axes_free, [-5,5]);

            view(app.axes_super,0,90);

            zlabel(app.axes_super, 'Superimposed distance to nearest vertex');
            app.UpdateButtonPushed;
            
          
        end

        % Callback function: axes_fixed, axes_super, center_resolution, 
        % ...and 3 other components
        function UpdateButtonPushed(app, event)

            
%             app.metalattice_spacing.Value=(1+cos(app.misorientation_rad.Value))/(sqrt(3)*(sin(app.misorientation_rad.Value)));
            w=(1+cos(app.misorientation_rad.Value))/(sqrt(3)*(sin(app.misorientation_rad.Value)));

%             xlim(app.axes_super, [-w,w]);
%             ylim(app.axes_super, [-w,w]);

            disp(app.X(end));
            disp(size(app.X(end)));
            

%             app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y);

%             app.free_lattice=get_lattice(app.misorientation_rad.Value, app.lattice_spacing.Value, app.x_translation.Value, app.y_translation.Value, app.X, app.Y,app.fourier_degree.Value);
            app.free_lattice=get_lattice(app.misorientation_rad.Value, app.lattice_spacing.Value, app.x_translation.Value, app.y_translation.Value, app.X, app.Y);

            plot_component_lattice(app.axes_free, app.free_lattice, app.X, app.Y);

            superlattice = (abs(app.fixed_lattice+app.free_lattice))/2;
            surf(app.axes_super, app.X, app.Y, superlattice);
            [center,peak]=track_nearest_center();
%             [center, peak]=get_nearest_center(app.X, app.Y, superlattice, app.last_center);
            
            
            set(gcf, 'renderer', 'zbuffer');
            plot_superlattice_interference(app.axes_super, app.X, app.Y, superlattice, app.center_resolution.Value, peak);
            hold(app.axes_super, "on")
            overlay_vectors(app.axes_super, center, app.last_center, app.x_translation.Value, app.y_translation.Value, app.misorientation_rad.Value,peak, app.axis_size.Value);
  
          
            hold(app.axes_super, "off");
            if app.fix_center=='Off'
                app.last_center=center;
            end
            

            if app.movement_bool==1
                app.last_center = center;
            end
            disp(center);


        end

        % Value changed function: axis_size, tick_spacing_denominator
        function axis_sizeValueChanged(app, event)

            app.tick_spacing.Value = app.lattice_spacing.Value/app.tick_spacing_denominator.Value;
            [app.X,app.Y]=get_plane_indices(app.lattice_spacing.Value, app.tick_spacing.Value, app.axis_size.Value);
%             app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y);

            plot_component_lattice(app.axes_fixed, app.fixed_lattice, app.X, app.Y);

%             xlim(app.movement_vector_plot,[-app.axis_size.Value,app.axis_size.Value]);
%             ylim(app.movement_vector_plot,[-app.axis_size.Value,app.axis_size.Value]);
            
            app.movement_bool=1;
            app.UpdateButtonPushed;
        end

        % Value changed function: fix_center
        function fix_centerValueChanged(app, event)
            if app.fix_center=='Off'
                disp('last center value')
                disp(app.last_center)
                app.last_center=[0, 0];
                app.UpdateButtonPushed;
            end
            
        end

        % Value changed function: misorientation
        function misorientationValueChanged(app, event)
            value = app.misorientation.Value;
            app.misorientation_rad.Value=pi/180*value;
            app.UpdateButtonPushed;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {197, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AxisSizeLabel = uilabel(app.LeftPanel);
            app.AxisSizeLabel.Text = 'Axis Size';
            app.axis_size = uieditfield(app.LeftPanel, 'numeric');
            app.axis_size.ValueChangedFcn = createCallbackFcn(app, @axis_sizeValueChanged, true);
            app.axis_size.Value = 10;
            app.CenterResolutionLabel = uilabel(app.LeftPanel);
            app.CenterResolutionLabel.Text = 'Center Resolution';
            app.center_resolution = uislider(app.LeftPanel);
            app.center_resolution.Limits = [0 2];
            app.center_resolution.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.center_resolution.Value = 0.5;
            app.PrecisionLabel = uilabel(app.LeftPanel);
            app.PrecisionLabel.Text = 'Ticks per lattice spacing';
            app.tick_spacing_denominator = uislider(app.LeftPanel);
            app.tick_spacing_denominator.Limits = [1 20];
            app.tick_spacing_denominator.ValueChangedFcn = createCallbackFcn(app, @axis_sizeValueChanged, true);
            app.tick_spacing_denominator.Value = 4;
            app.FreeLatticeControlsLabel = uilabel(app.LeftPanel);
            app.FreeLatticeControlsLabel.Text = 'Free Lattice Controls';
            app.ComputationalParametersLabel = uilabel(app.LeftPanel);
            app.ComputationalParametersLabel.Text = 'Computational Parameters';
            app.LessPreciseLabel = uilabel(app.LeftPanel);
            app.LessPreciseLabel.Text = 'Less Precise';
            app.MorePreciseLabel = uilabel(app.LeftPanel);
            app.MorePreciseLabel.Text = 'More Precise';
            app.ClearerLabel = uilabel(app.LeftPanel);
            app.ClearerLabel.Text = 'Clearer';
            app.FuzzierLabel = uilabel(app.LeftPanel);
            app.FuzzierLabel.Text = 'Fuzzier';
            app.FourierDegreeSliderLabel = uilabel(app.LeftPanel);
            app.FourierDegreeSliderLabel.Text = 'Fourier Degree';
            app.fourier_degree = uislider(app.LeftPanel);
            app.fourier_degree.Limits = [1 4];
            app.fourier_degree.MajorTicks = [1 2 3 4];
            app.fourier_degree.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.fourier_degree.MinorTicks = [];
            app.fourier_degree.Value = 1;
            app.MisorientationAngleEditFieldLabel = uilabel(app.LeftPanel);
            app.MisorientationAngleEditFieldLabel.Text = 'Misorientation Angle';
            app.misorientation = uieditfield(app.LeftPanel, 'numeric');
            app.misorientation.ValueChangedFcn = createCallbackFcn(app, @misorientationValueChanged, true);
            app.xtranslationEditFieldLabel = uilabel(app.LeftPanel);
            app.xtranslationEditFieldLabel.Text = 'x-translation';
            app.x_translation = uieditfield(app.LeftPanel, 'numeric');
            app.x_translation.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.ytranslationEditFieldLabel = uilabel(app.LeftPanel);
            app.ytranslationEditFieldLabel.Text = 'y-translation';
            app.y_translation = uieditfield(app.LeftPanel, 'numeric');
            app.y_translation.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.FixcenterSwitchLabel = uilabel(app.LeftPanel);
            app.FixcenterSwitchLabel.Text = 'Fix center';
            app.fix_center = uiswitch(app.LeftPanel, 'slider');
            app.fix_center.ValueChangedFcn = createCallbackFcn(app, @fix_centerValueChanged, true);
            app.fix_center.Value = 'On';
            app.RightPanel = uipanel(app.GridLayout);
            app.axes_super = uiaxes(app.RightPanel);
            title(app.axes_super, 'Superlattice - Top Down')
            xlabel(app.axes_super, 'X')
            ylabel(app.axes_super, 'Y')
            zlabel(app.axes_super, 'Z')
            app.axes_super.ButtonDownFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            colormap(app.axes_super, 'spring')
            app.axes_free = uiaxes(app.RightPanel);
            title(app.axes_free, 'Free Lattice')
            zlabel(app.axes_free, 'Z')
            app.axes_free.XTick = [];
            app.axes_free.YTick = [];
            colormap(app.axes_free, 'turbo')
            app.axes_fixed = uiaxes(app.RightPanel);
            title(app.axes_fixed, 'Fixed Lattice')
            zlabel(app.axes_fixed, 'Z')
            app.axes_fixed.XTick = [];
            app.axes_fixed.YTick = [];
            app.axes_fixed.ButtonDownFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            colormap(app.axes_fixed, 'turbo')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vector_solution_viz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end